classdef CellAnalysisForCurveAlign_prototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIfigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        ImageTab                     matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        ROImanagerTab                matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        ObjectsPanel                 matlab.ui.container.Panel
        ListBox                      matlab.ui.control.ListBox
        ListObjects                  matlab.ui.control.ListBox
        AnnotationsPanel             matlab.ui.container.Panel
        Panel_3                      matlab.ui.container.Panel
        ListBox_2                    matlab.ui.control.ListBox
        LogTab                       matlab.ui.container.Tab
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        FileMenu                     matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        ImportcellmaskMenu           matlab.ui.container.Menu
        ImportfiberfeaturesfileMenu  matlab.ui.container.Menu
        ExportMenu                   matlab.ui.container.Menu
        ToolsMenu                    matlab.ui.container.Menu
        MoveMenu                     matlab.ui.container.Menu
        RectangleMenu                matlab.ui.container.Menu
        PolygonMenu                  matlab.ui.container.Menu
        FreehandMenu                 matlab.ui.container.Menu
        ElipseMenu                   matlab.ui.container.Menu
        SpecifyMenu                  matlab.ui.container.Menu
        ViewMenu                     matlab.ui.container.Menu
        ShowanalysispanelMenu        matlab.ui.container.Menu
        ShowannotationsMenu          matlab.ui.container.Menu
        ShowsegmentationsMenu        matlab.ui.container.Menu
        MeasureMenu                  matlab.ui.container.Menu
        MeasurmentmanagerMenu        matlab.ui.container.Menu
        ShowobjectmeasurmentsMenu    matlab.ui.container.Menu
        ShowannotationROImeasurmentsMenu  matlab.ui.container.Menu
        AnalyzeMenu                  matlab.ui.container.Menu
        PreprocessingMenu            matlab.ui.container.Menu
        CellAnalysisMenu             matlab.ui.container.Menu
        TumorregionannotationMenu    matlab.ui.container.Menu
        FiberquantificationMenu      matlab.ui.container.Menu
        TACSscalculationMenu         matlab.ui.container.Menu
        Menu_2                       matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        UsersmanaulMenu              matlab.ui.container.Menu
        GithubWikipageMenu           matlab.ui.container.Menu
        GitHubsourcecodeMenu         matlab.ui.container.Menu
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        CAPannotations     % CurveAlign Plus annotations: ROI, tumor regions
        CAPobjects         % CurveAlign Plus objects: cell,fiber, tumor regions
        CAPmeasurements    % CurveAlign Plus measurements: annotations,objects
        figureOptions = struct('plotImage',1,'plotAnnotations',0,'plotObjects','0');
        figureH1
        boundary
        obje
        objectsView = struct('Index',[],'boundaryX',[],'boundaryY',[],'cellH1',{''},...
            'cellH2',{''},'Selection','','Name','','Type','');   
    end
    
    methods (Access = private)
        
        function plotImage(app)        
            app.figureH1 = imshow(app.CAPobjects.imageData2, 'Parent',app.UIAxes); 
            figureOptions.plotAnnotations = 1;
            if figureOptions.plotAnnotations == 1
                % plot individual cells
                objectColor = 'r';
                objectLineWidth = 2;
                objectsTemp = app.CAPobjects.cells;
                cellNumber = size(objectsTemp,2);                
                app.objectsView.boundaryX = cell(cellNumber,1);
                app.objectsView.boundaryY = cell(cellNumber,1);  
                app.objectsView.cellH1 = cell(cellNumber,1);

                for i = 1:cellNumber
                    app.objectsView.index(i) = i;
                    app.objectsView.type{i} = 'cell';
                    boundaryXY =   squeeze(objectsTemp(1,i).boundray);
                    app.objectsView.boundaryX{i} = [boundaryXY(1,:)';boundaryXY(1,1)];
                    app.objectsView.boundaryY{i} = [boundaryXY(2,:)';boundaryXY(2,1)];
                    app.objectsView.Name{i} = sprintf('%s%d',app.objectsView.type{i},i);
                    set(app.UIAxes,'NextPlot','add');
                    app.objectsView.cellH1{i,1} =plot(app.objectsView.boundaryY{i},app.objectsView.boundaryX{i},[objectColor '-'],'LineWidth',objectLineWidth, 'Parent',app.UIAxes); % 'Parent',figureH2)
                end
%                 disp('highlight selected objects...')
%                 pause
%                 app.plotSelection;
               % add the objects to the list
               %ListObjectsValueChanged
               app.ListObjects.Items = app.objectsView.Name;
            end

        end
   
        
        function plotSelection(app)
            %% hightlight selected cell(s)
            cellSelected = app.objectsView.Selection;   %cell selection from the GUI
            
            cellSelectedNumber = length(cellSelected);
            objectHighlightColor = 'y';
            objectHightLineWidth = 3;
            try
                highlightedCellNumber = size(app.objectsView.cellH2,1);
                for ii = 1:highlightedCellNumber
                    delete(app.objectsView.cellH2{ii,1})
                end
            catch err
                fprintf('%s \n', err.message)
            end
            cellH2 = cell(cellSelectedNumber,1);  % initialize the cellH
            ii = 0;
            for i = cellSelected
                fprintf('highlight object %d \n',i)
                ii = ii + 1;
                set(app.UIAxes,'NextPlot','add');
                app.objectsView.cellH2{ii,1} =plot(app.objectsView.boundaryY{i},app.objectsView.boundaryX{i},[objectHighlightColor '-'],'LineWidth',objectHightLineWidth,'Parent',app.UIAxes);
            end
   
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: CellAnalysisMenu
        function CellAnalysisMenuSelected(app, event)
            % get the saved detections
          % [object_data_Name,object_data_path]=uigetfile({'*.mat'},'Select segmentations',pwd,'MultiSelect','off'); 
           object_data_Name = 'guiTestdata1.mat';object_data_path = pwd;
           app.CAPobjects = load(fullfile(object_data_path,object_data_Name)); 
           app.plotImage;
           
        end

        % Value changed function: ListObjects
        function ListObjectsValueChanged(app, event)
            itemName = app.ListObjects.Value;   
            app.objectsView.Selection = str2num(strrep(itemName,'cell',''));
            app.plotSelection
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIfigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {723, 723};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {493, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIfigure = uifigure('Visible', 'off');
            app.UIfigure.Name = 'MATLAB App';
            app.UIfigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIfigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.UITable = uitable(app.ImageTab);
            app.UITable.ColumnName = {'Name'; 'Value'};
            app.UITable.RowName = {'Name, Path, Type, Width, Height, Dimensiions, Pixel Width, Pixel height'};
            app.ROImanagerTab = uitab(app.TabGroup);
            app.ROImanagerTab.Title = 'ROI manager';
            app.Panel = uipanel(app.ROImanagerTab);
            app.AnnotationsPanel = uipanel(app.Panel);
            app.AnnotationsPanel.Title = 'Annotations';
            app.ListBox_2 = uilistbox(app.AnnotationsPanel);
            app.ListBox_2.Items = {'Annotation 1', 'Annotation 2', 'Annotation 3', 'Annotation 4'};
            app.ListBox_2.Value = 'Annotation 1';
            app.Panel_3 = uipanel(app.AnnotationsPanel);
            app.ObjectsPanel = uipanel(app.Panel);
            app.ObjectsPanel.Title = 'Objects';
            app.ListObjects = uilistbox(app.ObjectsPanel);
            app.ListObjects.Items = {'Item 1'};
            app.ListObjects.ValueChangedFcn = createCallbackFcn(app, @ListObjectsValueChanged, true);
            app.ListBox = uilistbox(app.ObjectsPanel);
            app.ListBox.Items = {'None', 'Nuclei', 'Cell', 'Tumor', 'Stroma collagen', 'All'};
            app.ListBox.Value = 'Tumor';
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.FileMenu = uimenu(app.UIfigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open...';
            app.ImportcellmaskMenu = uimenu(app.FileMenu);
            app.ImportcellmaskMenu.Text = 'Import cell mask...';
            app.ImportfiberfeaturesfileMenu = uimenu(app.FileMenu);
            app.ImportfiberfeaturesfileMenu.Text = 'Import fiber features file..';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.ToolsMenu = uimenu(app.UIfigure);
            app.ToolsMenu.Text = 'Tools';
            app.MoveMenu = uimenu(app.ToolsMenu);
            app.MoveMenu.Text = 'Move';
            app.RectangleMenu = uimenu(app.ToolsMenu);
            app.RectangleMenu.Text = 'Rectangle';
            app.PolygonMenu = uimenu(app.ToolsMenu);
            app.PolygonMenu.Text = 'Polygon';
            app.FreehandMenu = uimenu(app.ToolsMenu);
            app.FreehandMenu.Text = 'Free hand';
            app.ElipseMenu = uimenu(app.ToolsMenu);
            app.ElipseMenu.Text = 'Elipse';
            app.SpecifyMenu = uimenu(app.ToolsMenu);
            app.SpecifyMenu.Text = 'Specify';
            app.ViewMenu = uimenu(app.UIfigure);
            app.ViewMenu.Text = 'View';
            app.ShowanalysispanelMenu = uimenu(app.ViewMenu);
            app.ShowanalysispanelMenu.Text = 'Show analysis panel';
            app.ShowannotationsMenu = uimenu(app.ViewMenu);
            app.ShowannotationsMenu.Text = 'Show annotations';
            app.ShowsegmentationsMenu = uimenu(app.ViewMenu);
            app.ShowsegmentationsMenu.Text = 'Show segmentations';
            app.MeasureMenu = uimenu(app.UIfigure);
            app.MeasureMenu.Text = 'Measure';
            app.MeasurmentmanagerMenu = uimenu(app.MeasureMenu);
            app.MeasurmentmanagerMenu.Text = 'Measurment manager';
            app.ShowobjectmeasurmentsMenu = uimenu(app.MeasureMenu);
            app.ShowobjectmeasurmentsMenu.Text = 'Show object measurments';
            app.ShowannotationROImeasurmentsMenu = uimenu(app.MeasureMenu);
            app.ShowannotationROImeasurmentsMenu.Text = 'Show annotation(ROI) measurments';
            app.AnalyzeMenu = uimenu(app.UIfigure);
            app.AnalyzeMenu.Text = 'Analyze';
            app.PreprocessingMenu = uimenu(app.AnalyzeMenu);
            app.PreprocessingMenu.Text = 'Preprocessing';
            app.CellAnalysisMenu = uimenu(app.AnalyzeMenu);
            app.CellAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @CellAnalysisMenuSelected, true);
            app.CellAnalysisMenu.Text = 'Cell Analysis';
            app.TumorregionannotationMenu = uimenu(app.AnalyzeMenu);
            app.TumorregionannotationMenu.Text = 'Tumor region annotation';
            app.FiberquantificationMenu = uimenu(app.AnalyzeMenu);
            app.FiberquantificationMenu.Text = 'Fiber quantification';
            app.TACSscalculationMenu = uimenu(app.AnalyzeMenu);
            app.TACSscalculationMenu.Text = 'TACSs calculation';
            app.Menu_2 = uimenu(app.UIfigure);
            app.HelpMenu = uimenu(app.UIfigure);
            app.HelpMenu.Text = 'Help';
            app.UsersmanaulMenu = uimenu(app.HelpMenu);
            app.UsersmanaulMenu.Text = 'User''s manaul';
            app.GithubWikipageMenu = uimenu(app.HelpMenu);
            app.GithubWikipageMenu.Text = 'Github Wiki page';
            app.GitHubsourcecodeMenu = uimenu(app.HelpMenu);
            app.GitHubsourcecodeMenu.Text = 'GitHub source code';
            app.ContextMenu = uicontextmenu(app.UIfigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.LeftPanel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIfigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellAnalysisForCurveAlign_prototype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIfigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIfigure)
        end
    end
end