classdef TumorRegionAnnotationGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TumorRegionDetectionoptionsUIFigure  matlab.ui.Figure
        SliderDensityParameter          matlab.ui.control.Slider
        SliderGrideRow                  matlab.ui.control.Slider
        SliderGridCol                   matlab.ui.control.Slider
        GridRowsEditField               matlab.ui.control.NumericEditField
        GridRowsEditFieldLabel          matlab.ui.control.Label
        ParametersEditField             matlab.ui.control.NumericEditField
        ParametersEditFieldLabel        matlab.ui.control.Label
        GridColumnsEditField            matlab.ui.control.NumericEditField
        GridColumnsEditFieldLabel       matlab.ui.control.Label
        SendtoROImanagerCheckBox        matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        PathtoimageEditField            matlab.ui.control.EditField
        PathtoimageEditFieldLabel       matlab.ui.control.Label
        DefaultparametersCheckBox       matlab.ui.control.CheckBox
        ImagetypeDropDown               matlab.ui.control.DropDown
        ImagetypeDropDownLabel          matlab.ui.control.Label
        AnnotationMethodsDropDown       matlab.ui.control.DropDown
        AnnotationMethodsDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp % main app class handle
        parameterOptions = struct('imagePath','','imageName','','imageType','HE bright field',...
            'method','ranking', 'gridColumn', 50,'gridRow', 50,'parameter_threshold',5,... 
            'defaultParameters',1,'sendtoROImanager',1);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
            app.parameterOptions.imagePath = mainApp.imagePath;
            app.parameterOptions.imageName = mainApp.imageName;
            app.PathtoimageEditField.Value = fullfile(app.parameterOptions.imagePath,...
               app.parameterOptions.imageName);
            
        end

        % Callback function: ImagetypeDropDown, ImagetypeDropDown
        function ImagetypeDropDownValueChanged(app, event)
            value = app.ImagetypeDropDown.Value;
            app.parameterOptions.imageType = app.ImagetypeDropDown.Value;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            %         parameterOptions = struct('imagePath','','imageName','','imageType','HE bright field',...
            %             'method','ranking', 'gridColumn', 50,'gridRow', 50,'parameter_threshold',5,...
            %             'defaultParameters',1,'sendtoROImanager',1);
            if ~strcmp(app.parameterOptions.imageType,'HE bright field')
                disp('Only available for HE bright field images so far')
                return
            end
            if strcmp(app.AnnotationMethodsDropDown.Value,'Ranking')
                thresholdValue = app.ParametersEditField.Value;
                turmorDetected = imageCardTumor(app.CallingApp.imageName,'Rank',...
                    [50 50],thresholdValue,50);
                app.CallingApp.CAPannotations.tumorAnnotations.tumorArray = turmorDetected.tumorArray;
                app.CallingApp.figureOptions.plotAnnotations = 1;
                app.CallingApp.plotImage_public;
            else
                fprintf('This tumor annotation method-%s is not available \ n',app.AnnotationMethodsDropDown.Value)
            end
        end

        % Value changed function: SliderDensityParameter
        function SliderDensityParameterValueChanged(app, event)
            value = app.SliderDensityParameter.Value;
            app.SliderDensityParameter.Value = round(value);
            app.parameterOptions.parameter_threshold =  app.SliderDensityParameter.Value;
            app.ParametersEditField.Value = app.SliderDensityParameter.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TumorRegionDetectionoptionsUIFigure = uifigure('Visible', 'off');
            app.TumorRegionDetectionoptionsUIFigure.Name = 'Tumor Region Detection-options';
            app.AnnotationMethodsDropDownLabel = uilabel(app.TumorRegionDetectionoptionsUIFigure);
            app.AnnotationMethodsDropDownLabel.Text = 'Annotation Methods';
            app.AnnotationMethodsDropDown = uidropdown(app.TumorRegionDetectionoptionsUIFigure);
            app.AnnotationMethodsDropDown.Items = {'Ranking', 'Area threshold (in Pixels)', 'Distance Based'};
            app.AnnotationMethodsDropDown.Value = 'Ranking';
            app.ImagetypeDropDownLabel = uilabel(app.TumorRegionDetectionoptionsUIFigure);
            app.ImagetypeDropDownLabel.Text = 'Image type';
            app.ImagetypeDropDown = uidropdown(app.TumorRegionDetectionoptionsUIFigure);
            app.ImagetypeDropDown.Items = {'HE bright field', 'Fluorescence_1-Channel', 'Fluorescence_2-Channel', 'Two-channel', 'Phase contrast', 'Gray scale'};
            app.ImagetypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ImagetypeDropDownValueChanged, true);
            app.ImagetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagetypeDropDownValueChanged, true);
            app.ImagetypeDropDown.Value = 'HE bright field';
            app.DefaultparametersCheckBox = uicheckbox(app.TumorRegionDetectionoptionsUIFigure);
            app.DefaultparametersCheckBox.Text = 'Default parameters';
            app.DefaultparametersCheckBox.Value = true;
            app.PathtoimageEditFieldLabel = uilabel(app.TumorRegionDetectionoptionsUIFigure);
            app.PathtoimageEditFieldLabel.Text = 'Path to image';
            app.PathtoimageEditField = uieditfield(app.TumorRegionDetectionoptionsUIFigure, 'text');
            app.PathtoimageEditField.Editable = 'off';
            app.OKButton = uibutton(app.TumorRegionDetectionoptionsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.TumorRegionDetectionoptionsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SendtoROImanagerCheckBox = uicheckbox(app.TumorRegionDetectionoptionsUIFigure);
            app.SendtoROImanagerCheckBox.Text = 'Send to ROI manager';
            app.SendtoROImanagerCheckBox.Value = true;
            app.GridColumnsEditFieldLabel = uilabel(app.TumorRegionDetectionoptionsUIFigure);
            app.GridColumnsEditFieldLabel.Text = 'Grid Columns';
            app.GridColumnsEditField = uieditfield(app.TumorRegionDetectionoptionsUIFigure, 'numeric');
            app.GridColumnsEditField.Value = 50;
            app.ParametersEditFieldLabel = uilabel(app.TumorRegionDetectionoptionsUIFigure);
            app.ParametersEditFieldLabel.Text = 'Parameters';
            app.ParametersEditField = uieditfield(app.TumorRegionDetectionoptionsUIFigure, 'numeric');
            app.ParametersEditField.Value = 5;
            app.GridRowsEditFieldLabel = uilabel(app.TumorRegionDetectionoptionsUIFigure);
            app.GridRowsEditFieldLabel.Text = 'Grid Rows';
            app.GridRowsEditField = uieditfield(app.TumorRegionDetectionoptionsUIFigure, 'numeric');
            app.GridRowsEditField.Value = 50;
            app.SliderGridCol = uislider(app.TumorRegionDetectionoptionsUIFigure);
            app.SliderGridCol.Value = 50;
            app.SliderGrideRow = uislider(app.TumorRegionDetectionoptionsUIFigure);
            app.SliderGrideRow.Value = 50;
            app.SliderDensityParameter = uislider(app.TumorRegionDetectionoptionsUIFigure);
            app.SliderDensityParameter.Limits = [0 10];
            app.SliderDensityParameter.MajorTicks = [0 2 4 6 8 10];
            app.SliderDensityParameter.MajorTickLabels = {'0', '2', '4', '6', '8', '10'};
            app.SliderDensityParameter.ValueChangedFcn = createCallbackFcn(app, @SliderDensityParameterValueChanged, true);
            app.SliderDensityParameter.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SliderDensityParameter.Value = 5;
            % Show the figure after all components are created
            app.TumorRegionDetectionoptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TumorRegionAnnotationGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TumorRegionDetectionoptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TumorRegionDetectionoptionsUIFigure)
        end
    end
end