classdef cellAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CellsUIFigure  matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        UITable        matlab.ui.control.Table
    end

    
    properties (Access = public)
        imgName
        imgPath
        cells
        cellTable
        drawing
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imgName, imgPath)
            if ~exist('imgPath','var')
                app.imgPath = "";
                img = imageCard(imgName);
            else
                app.imgPath = imgPath;
                img = imageCard(imgName,imgPath);
            end
            app.imgName = imgName;
            fullfileImg = app.imgPath + app.imgName;
            numCells = length(img.cellArray);
            app.cells = img.cellArray;
            app.cellTable = [];
            for i=1:numCells
                cell = img.cellArray(i);
                X = getX(cell);
                Y = getY(cell);
                area = getArea(cell);
                perimeter = getPerimeter(cell);
                majorAxis = getMajorAxis(cell);
                minorAxis = getMinorAxis(cell);
                circularity = getCircularity(cell);
                aspectRatio = getAspectRatio(cell);
                vampireShapeMode = getVampireShapeMode(cell);
                app.cellTable = [app.cellTable; getIndex(cell) X Y area perimeter ...
                    majorAxis minorAxis circularity aspectRatio vampireShapeMode ...
                    [cell.compactness] [cell.eccentricity]];
            end
            C = num2cell(app.cellTable);
            tdata = cell2table(C,'VariableNames',{'Index','Position X','Position Y','Area','Perimeter','Major Axis',...
                'Minor Axis','Circularity','Aspect Ratio','Vampire Shape','Compactness','Eccentricity'});
            app.UITable.Data = tdata;
            figure
            imshow(fullfileImg)
            hold on
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            delete(app.drawing)
            cell = app.cells(indices);
            sizeCoord = size([cell.boundray]);
            boundaries = getBoundray(cell);
            for i=1:sizeCoord(3)
                X(i) = boundaries(1,1,i);
                Y(i) = boundaries(1,2,i);
            end
            for i=1:(sizeCoord(3)-1)
                app.drawing(i) = plot([Y(i); Y(i+1)], [X(i); X(i+1)],'-r','LineWidth',5);
            end
            app.drawing(sizeCoord(3)) = plot([Y(sizeCoord(3)); Y(1)], [X(sizeCoord(3)); X(1)],'-r','LineWidth',5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CellsUIFigure = uifigure('Visible', 'off');
            app.CellsUIFigure.Name = 'Cells';
            app.GridLayout = uigridlayout(app.CellsUIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Index'; 'Position X'; 'Position Y'; 'Area'; 'Perimeter'; 'Major Axis'; 'Minor Axis'; 'Circularity'; 'Aspect Ratio'; 'Vampire Shape'; 'Compactness'; 'Eccentricity'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            % Show the figure after all components are created
            app.CellsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CellsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CellsUIFigure)
        end
    end
end