classdef cellanalysisAnnotationMeasurement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnnotationmeasurmentsUIFigure  matlab.ui.Figure
        SaveButton                     matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        HistogramsButton               matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
    end

    
    properties (Access = public)
        CallingApp % main app class handle
        annotationMeasurement   % structure to show the annotationMeasurement;  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainAPP)
            app.UITable.ColumnName = {'Image','Name','Class','Parent','Center-X','Center-Y',...
                'Tumor-Area','Tumor-Perimeter','Cell-Number','Cell-Area','Cell-Orientation','Cell-Alignment',...
                'Fiber-Number','Fiber-Orientation','Fiber-Alignment'};         
%             measurementName = {'position','orientation','area','circularity', 'shapemode'};
            imageName = mainAPP.imageName;
%             annotationNumber = size(mainAPP.CAPannotations.tumorAnnotations.tumorArray,2);
% annotation selected
            annotationsSelected = mainAPP.annotationView.Selection;
            annotationsSelectedNumber = length(annotationsSelected);
            annotationNumber = annotationsSelectedNumber;
%             tumorData = mainAPP.CAPannotations.tumorAnnotations.tumorArray(1,annotationNumber);
%             tumorBoundaryCol = tumorData.boundary(:,2);
%             tumorBoundaryRow = tumorData.boundary(:,1);
%             imageH = mainAPP.CAPimage.imageInfo.Height;
%             imageW = mainAPP.CAPimage.imageInfo.Width;
%             tumorMask = poly2mask(tumorBoundaryCol,tumorBoundaryRow,imageH,imageW);
%             stats = regionprops(tumorMask,'Centroid','Area','Perimeter');
            stats = mainAPP.CAPannotations.tumorAnnotations.statsArray{1,annotationsSelected};
            tumorCenterX = stats.Centroid(1,1);
            tumorCenterY = stats.Centroid(1,2);
            tumorArea = stats.Area;
            tumorPerimetor = stats.Perimeter;            
            % cell selected
            objectsSelected = mainAPP.objectsView.Selection;
            objectsSelectedNumber = length(objectsSelected);
            cellNumber = objectsSelectedNumber;
            % initialize cell info
            cellArea = 0;
            cellOverallOrientation = nan;
            cellAlignment = nan;
            
            cellOrientations = nan(cellNumber,1);
            
            for i = 1:cellNumber
                iCell = objectsSelected(i);
                cellArea = cellArea + mainAPP.CAPobjects.cells.cellArray(1,iCell).area;
                tempOrientation = mainAPP.CAPobjects.cells.cellArray(1,iCell).orientation;
                if tempOrientation < 0
                    tempOrientation = 180 + tempOrientation;
                end
                cellOrientations(i,1) = tempOrientation;
            end
            % use circular statistic to calculate overall orientation and
            % alignment
            vals = cellOrientations;% fibers satisfying boundary conditions
            vals2 = 2*(vals*pi/180); %convert to radians and mult by 2, then divide by 2: this is to scale 0 to 180 up to 0 to 360, this makes the analysis circular, since we are using orientations and not directions
            cellAlignment = circ_r(vals2); %large alignment means angles are highly aligned, result is between 0 and 1
            aveAngle = (180/pi)*circ_mean(vals2)/2;
            aveAngle = mod(180+aveAngle,180);
            cellOverallOrientation = sprintf('%3.1f',aveAngle);
            
            % fiber detected
            % initialize fiber info
            fiberNumber = nan;
            fiberOverallOrientation = nan;
            fiberAlignment = nan;
            fibersSelected = mainAPP.fibersView.Selection;
            fibersSelectedNumber = length(fibersSelected);
            fiberNumber = fibersSelectedNumber;
            if fiberNumber > 0
                fiberOrientations = mainAPP.CAPobjects.fibers.fibFeat(fibersSelected,4);
                % use circular statistic to calculate overall orientation and
                % alignment
                vals = fiberOrientations;% fibers satisfying boundary conditions
                vals2 = 2*(vals*pi/180); %convert to radians and mult by 2, then divide by 2: this is to scale 0 to 180 up to 0 to 360, this makes the analysis circular, since we are using orientations and not directions
                fiberAlignment = circ_r(vals2); %large alignment means angles are highly aligned, result is between 0 and 1
                aveAngle = (180/pi)*circ_mean(vals2)/2;
                aveAngle = mod(180+aveAngle,180);
                fiberOverallOrientation = sprintf('%3.1f',aveAngle);
            end
   
            if strcmp(mainAPP.annotationView.Selection,'') && mainAPP.CAPannotations.cellDetectionFlag == 0
                annotationName = 'Tumor-annotation';
                annotationNumber = size(mainAPP.CAPannotations.tumorAnnotations.tumorArray,2);
                return;
            elseif ~strcmp(mainAPP.annotationView.Selection,'') && mainAPP.CAPannotations.cellDetectionFlag == 0
                annotationName = 'Tumor-annotation';
                annotationNumber = 0;
                app.UITable.Data = '';
                return;
            else
                annotationSelected = mainAPP.annotationView.Selection;   %cell selection from the GUI
                annotationName = sprintf('Tumor-annotation%d', annotationSelected);
            end
            % fill the list table
            measurementsNumber = size(app.UITable.ColumnName,1);
            tableData = cell(annotationNumber,measurementsNumber);  
            for i = 1:annotationNumber  
                if strcmp(mainAPP.CAPimage.CellanalysisMethod,'StarDist')
                    tableData{i,1} = imageName;
                    tableData{i,2} = sprintf('annotation%d',annotationsSelected(i));
                    tableData{i,3} = 'Tumor';
                    tableData{i,4} = 'Image';%'TumorAnnotation';
                    tableData{i,5} = round(tumorCenterX);
                    tableData{i,6} = round(tumorCenterY);
                    tableData{i,7} = tumorArea;
                    tableData{i,8} = round(tumorPerimetor);
                    tableData{i,9} = cellNumber;
                    tableData{i,10} = cellArea;
                    tableData{i,11} = cellOverallOrientation;
                    tableData{i,12} = cellAlignment;
                    tableData{i,13} = fiberNumber;
                    tableData{i,14} = fiberOverallOrientation;
                    tableData{i,15} = fiberAlignment;      
%                 else %cellpose and deepcell
%                     tableData{i,1} = imageName;
%                     tableData{i,2} = sprintf('cell%d',i);
%                     tableData{i,3} = 'cell';
%                     tableData{i,4} = annotationName; %'TumorAnnotation';
%                     tableData{i,5} = mainAPP.CAPannotations.tumorAnnotations.tumorArray(1,iS).Position(1);
%                     tableData{i,6} = mainAPP.CAPannotations.tumorAnnotations.tumorArray(1,iS).Position(2);
%                     tableData{i,7} = mainAPP.CAPannotations.tumorAnnotations.tumorArray(1,iS).Orientation;
%                     tableData{i,8} = mainAPP.CAPannotations.tumorAnnotations.tumorArray(1,iS).Area;
%                     tableData{i,9} = mainAPP.CAPannotations.tumorAnnotations.tumorArray(1,iS).Circularity;
%                     tableData{i,10} = '';
                end
            end
            app.UITable.Data = tableData;
     
  
        end

        % Button down function: UITable
        function UITableButtonDown(app, event)
            
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnnotationmeasurmentsUIFigure = uifigure('Visible', 'off');
            app.AnnotationmeasurmentsUIFigure.Name = 'Annotation measurments';
            app.UITable = uitable(app.AnnotationmeasurmentsUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ButtonDownFcn = createCallbackFcn(app, @UITableButtonDown, true);
            app.HistogramsButton = uibutton(app.AnnotationmeasurmentsUIFigure, 'push');
            app.HistogramsButton.Text = 'Histograms';
            app.CloseButton = uibutton(app.AnnotationmeasurmentsUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SaveButton = uibutton(app.AnnotationmeasurmentsUIFigure, 'push');
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.AnnotationmeasurmentsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellanalysisAnnotationMeasurement(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnnotationmeasurmentsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnnotationmeasurmentsUIFigure)
        end
    end
end