classdef intersectionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UITable                       matlab.ui.control.Table
        DeleteButton                  matlab.ui.control.Button
        ClearPointButton              matlab.ui.control.Button
        ExportButton                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        RefreshButton                 matlab.ui.control.Button
        PointsnotcloserthanEditFieldLabel  matlab.ui.control.Label
        PointsnotcloserthanEditField  matlab.ui.control.NumericEditField
        CalculateButton               matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        ShowIndexButton               matlab.ui.control.Button
        AddapointXLabel               matlab.ui.control.Label
        AddapointXEditField           matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        AddButton                     matlab.ui.control.Button
        ShowOriginalPictureButton     matlab.ui.control.Button
        CombineEditFieldLabel         matlab.ui.control.Label
        CombineEditField              matlab.ui.control.NumericEditField
        andEditFieldLabel             matlab.ui.control.Label
        andEditField                  matlab.ui.control.NumericEditField
        CombineButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        size;
        rowNumber;
        intersectionTable;
        image;
        value;
        ogData;
        addX;
        addY;
        combine1;
        combine2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imgName, imgSize, IP)
            app.image = imgName;
            app.intersectionTable = [];
            app.ogData = [];
            for i = 1:length(IP)
                app.intersectionTable = [app.intersectionTable; IP(i,1) IP(i,2) i];
                app.ogData = [app.intersectionTable; IP(i,1) IP(i,2) i];
            end
            C = num2cell(app.intersectionTable);
            tdata = cell2table(C,'VariableNames',{'X', 'Y', 'Index'});
            img = imread(imgName);
            imshow(img, 'parent', app.UIAxes);
            hold(app.UIAxes, 'on')
            title(app.UIAxes, 'fiber results');
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
            plot(app.UIAxes,app.intersectionTable(:,1),app.intersectionTable(:,2),'b.','MarkerSize',15);
            app.UITable.Data = tdata;
            app.size = imgSize;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.rowNumber = event.Indices;
            x = app.intersectionTable(indices(1),1);
            y = app.intersectionTable(indices(1),2);
            plot(app.UIAxes,x,y,'r.','MarkerSize',15);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
           indices = app.rowNumber;
           x = app.intersectionTable(indices(1),1);
           y = app.intersectionTable(indices(1),2);
           plot(app.UIAxes,x,y,'Marker','X','Color','blue','LineWidth',2,'MarkerSize',15);
           app.UITable.Data(indices(1),:) = [];
           app.intersectionTable(indices(1),:) = [];
        end

        % Button pushed function: ClearPointButton
        function ClearPointButtonPushed(app, event)
            indices = app.rowNumber;
            x = app.intersectionTable(indices(1),1);
            y = app.intersectionTable(indices(1),2);
            plot(app.UIAxes,x,y,'b.','MarkerSize',15);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            hold(app.UIAxes,'off')
            C = num2cell(app.intersectionTable);
            tdata = cell2table(C,'VariableNames',{'X', 'Y', 'Index'});
            img = imread(app.image);
            imshow(img, 'parent', app.UIAxes);
            hold(app.UIAxes, 'on')
            title(app.UIAxes, 'fiber results');
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
            plot(app.UIAxes,app.intersectionTable(:,1),app.intersectionTable(:,2),'b.','MarkerSize',15);
            app.UITable.Data = tdata;
            C = num2cell(app.intersectionTable);
            tdata = cell2table(C,'VariableNames',{'X', 'Y', 'Index'});
            app.UITable.Data = tdata;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            IP = app.intersectionTable;
            for i = 1:length(IP)
                IP(i,3) = 1;
            end
            uisave('IP','intersection_points')
        end

        % Value changed function: PointsnotcloserthanEditField
        function PointsnotcloserthanEditFieldValueChanged(app, event)
            app.value = app.PointsnotcloserthanEditField.Value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            IP = app.intersectionTable;
            sizeIMG = app.size;
            distance = app.value;
            app.intersectionTable = ipCombineRegions(IP, sizeIMG, distance);
            RefreshButtonPushed(app, event)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.intersectionTable = app.ogData;
        end

        % Button pushed function: ShowIndexButton
        function ShowIndexButtonPushed(app, event)
            index = string(app.intersectionTable(:,3));
            x = app.intersectionTable(:,1);
            y = app.intersectionTable(:,2);
            text(app.UIAxes,x,y,index,'BackgroundColor','y','FontSize',15); 
        end

        % Value changed function: AddapointXEditField
        function AddapointXEditFieldValueChanged(app, event)
            app.addX = app.AddapointXEditField.Value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.addY = app.YEditField.Value;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            lengthOfIP = length(app.intersectionTable);
            index = app.intersectionTable(lengthOfIP,3) + 1;
            app.intersectionTable = [app.intersectionTable; app.addX app.addY index];
            RefreshButtonPushed(app, event)
        end

        % Button pushed function: ShowOriginalPictureButton
        function ShowOriginalPictureButtonPushed(app, event)
            hold(app.UIAxes,'off')
            img = imread(app.image);
            imshow(img, 'parent', app.UIAxes);
            hold(app.UIAxes, 'on')
            title(app.UIAxes, 'fiber results');
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.intersectionTable(indices(1),indices(2)) = newData;
            RefreshButtonPushed(app, event)
        end

        % Value changed function: CombineEditField
        function CombineEditFieldValueChanged(app, event)
            app.combine1 = app.CombineEditField.Value;
        end

        % Value changed function: andEditField
        function andEditFieldValueChanged(app, event)
            app.combine2 = app.andEditField.Value;
        end

        % Button pushed function: CombineButton
        function CombineButtonPushed(app, event)
            found1 = false;
            found2 = false;
            for i = 1:length(app.intersectionTable)
                if app.intersectionTable(i,3) == app.combine1
                    x1 = app.intersectionTable(i,1);
                    y1 = app.intersectionTable(i,2);
                    found1 = true;
                end
                if app.intersectionTable(i,3) == app.combine2
                    x2 = app.intersectionTable(i,1);
                    y2 = app.intersectionTable(i,2);
                    found2 = true;
                end
            end
            if found1 && found2
                xadd = (x1 + x2) / 2;
                yadd = (y1 + y2) / 2;
                lengthOfIP = length(app.intersectionTable);
                index = app.intersectionTable(lengthOfIP,3) + 1;
                app.intersectionTable = [app.intersectionTable; xadd yadd index];
                for i = 1:length(app.intersectionTable)
                    if app.intersectionTable(i,3) == app.combine1
                        app.UITable.Data(i,:) = [];
                        app.intersectionTable(i,:) = [];
                        break
                    end
                end
                for i = 1:length(app.intersectionTable)
                    if app.intersectionTable(i,3) == app.combine2
                        app.UITable.Data(i,:) = [];
                        app.intersectionTable(i,:) = [];
                        break
                    end
                end
                RefreshButtonPushed(app, event)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'; 'Index'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = {'Delete'; ''};
            app.ClearPointButton = uibutton(app.UIFigure, 'push');
            app.ClearPointButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPointButtonPushed, true);
            app.ClearPointButton.Text = {'Clear Point'; ''};
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = {'Export'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.PointsnotcloserthanEditFieldLabel = uilabel(app.UIFigure);
            app.PointsnotcloserthanEditFieldLabel.Text = {'Points not closer than:'; ''};
            app.PointsnotcloserthanEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointsnotcloserthanEditField.ValueChangedFcn = createCallbackFcn(app, @PointsnotcloserthanEditFieldValueChanged, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = {'Calculate'; ''};
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ShowIndexButton = uibutton(app.UIFigure, 'push');
            app.ShowIndexButton.ButtonPushedFcn = createCallbackFcn(app, @ShowIndexButtonPushed, true);
            app.ShowIndexButton.Text = {'Show Index'; ''};
            app.AddapointXLabel = uilabel(app.UIFigure);
            app.AddapointXLabel.Text = 'Add a point:   X';
            app.AddapointXEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddapointXEditField.ValueChangedFcn = createCallbackFcn(app, @AddapointXEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = {'Add'; ''};
            app.ShowOriginalPictureButton = uibutton(app.UIFigure, 'push');
            app.ShowOriginalPictureButton.ButtonPushedFcn = createCallbackFcn(app, @ShowOriginalPictureButtonPushed, true);
            app.ShowOriginalPictureButton.Text = 'Show Original Picture';
            app.CombineEditFieldLabel = uilabel(app.UIFigure);
            app.CombineEditFieldLabel.Text = 'Combine';
            app.CombineEditField = uieditfield(app.UIFigure, 'numeric');
            app.CombineEditField.ValueChangedFcn = createCallbackFcn(app, @CombineEditFieldValueChanged, true);
            app.andEditFieldLabel = uilabel(app.UIFigure);
            app.andEditFieldLabel.Text = 'and';
            app.andEditField = uieditfield(app.UIFigure, 'numeric');
            app.andEditField.ValueChangedFcn = createCallbackFcn(app, @andEditFieldValueChanged, true);
            app.CombineButton = uibutton(app.UIFigure, 'push');
            app.CombineButton.ButtonPushedFcn = createCallbackFcn(app, @CombineButtonPushed, true);
            app.CombineButton.Text = 'Combine';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = intersectionGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end