classdef hist_parameterVis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TitleEditField            matlab.ui.control.EditField
        TitleEditFieldLabel       matlab.ui.control.Label
        YAxisTitleEditField       matlab.ui.control.EditField
        YAxisTitleEditFieldLabel  matlab.ui.control.Label
        XAxisTitleEditField       matlab.ui.control.EditField
        XAxisTitleEditFieldLabel  matlab.ui.control.Label
        FiberColorEditField       matlab.ui.control.EditField
        FiberColorEditFieldLabel  matlab.ui.control.Label
        CellColorEditField        matlab.ui.control.EditField
        CellColorEditFieldLabel   matlab.ui.control.Label
        CancelButton              matlab.ui.control.Button
        ApplyButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp % main app class handle
        app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainAPP)
            app.CallingApp = mainAPP;
            app.UIFigure.Name = 'Histogram Options';
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.CallingApp.X_Title = app.XAxisTitleEditField;
            app.CallingApp.Y_Title = app.YAxisTitleEditField;
            app.CallingApp.FiberColor = app.FiberColorEditField;
            app.CallingApp.CellColor = app.CellColorEditField;
            app.delete;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CellColorEditFieldLabel = uilabel(app.UIFigure);
            app.CellColorEditFieldLabel.Text = 'Cell Color           #';
            app.CellColorEditField = uieditfield(app.UIFigure, 'text');
            app.CellColorEditField.Value = '0072BD';
            app.FiberColorEditFieldLabel = uilabel(app.UIFigure);
            app.FiberColorEditFieldLabel.Text = 'Fiber Color         #';
            app.FiberColorEditField = uieditfield(app.UIFigure, 'text');
            app.FiberColorEditField.Value = 'D95319';
            app.XAxisTitleEditFieldLabel = uilabel(app.UIFigure);
            app.XAxisTitleEditFieldLabel.Text = 'X-Axis Title';
            app.XAxisTitleEditField = uieditfield(app.UIFigure, 'text');
            app.XAxisTitleEditField.Value = 'Orientation';
            app.YAxisTitleEditFieldLabel = uilabel(app.UIFigure);
            app.YAxisTitleEditFieldLabel.Text = 'Y-Axis Title';
            app.YAxisTitleEditField = uieditfield(app.UIFigure, 'text');
            app.YAxisTitleEditField.Value = 'Frequency';
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.TitleEditField.Value = 'Frequency of Orientation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hist_parameterVis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end