classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CAvisualization                matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        VisualizationTab               matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        OpenfeatureFileButton          matlab.ui.control.Button
        FileListListBoxLabel           matlab.ui.control.Label
        FileListListBox                matlab.ui.control.ListBox
        FeatureListListBoxLabel        matlab.ui.control.Label
        FeatureListListBox             matlab.ui.control.ListBox
        PlottingOptionsButtonGroup     matlab.ui.container.ButtonGroup
        LocationButton                 matlab.ui.control.RadioButton
        BoxplotButton                  matlab.ui.control.RadioButton
        HistogramButton                matlab.ui.control.RadioButton
        HeatmapButton                  matlab.ui.control.RadioButton
        UIAxes                         matlab.ui.control.UIAxes
        BinNumberSliderLabel           matlab.ui.control.Label
        BinNumberSlider                matlab.ui.control.Slider
        HeatmapcontrolPanel            matlab.ui.container.Panel
        Max_Filter_SizeEditFieldLabel  matlab.ui.control.Label
        Max_Filter_SizeEditField       matlab.ui.control.NumericEditField
        Gaussian_Filter_SizeEditFieldLabel  matlab.ui.control.Label
        Gaussian_Filter_SizeEditField  matlab.ui.control.NumericEditField
        Switch2                        matlab.ui.control.Switch
        FeatureValuesTab               matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        OriginalimageTab               matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        OverlayImageTab                matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filePath = pwd;  % file path 
        fileList = cell(1); % all the files opened
        featureList = cell(1); % all the featurs loaded
        fileSelected; % image to be plotted
        featureSelected; % feature to be plotted
        plotOption;     % option of plotting   
        Data;          % Data from the feature file
        BinNumber = 10;       % for the histogram
        Histogram = gobjects(0); 
        %heatmap parameters
        STDfilter_size;
        SQUAREmaxfilter_size = 12;
        GAUSSIANdiscfilter_sigma = 4;
   
    end
    
    
    methods (Access = private)
        
        function LocationScatterPlot(app)
%             % Update X and Y Labels
%             app.UIAxes.XLabel.String = '';
%             app.UIAxes.YLabel.String = '';
%             % Dont show the histogram slider
%             app.BinNumberSliderLabel.Visible = 'off';
%             app.BinNumberSlider.Visible = 'off';
        end
        
         
        function [rawmap, procmap] = CAheatmap(app)
            % Outputs
            % rawmap: 2D image where grey levels indicate angle information
            % procmap: A filtered version of the rawmap
            J = app.Data.imageHeight;
            I = app.Data.imageWidth;
            rawmap = nan(J,I);
            procmap = rawmap;
            featureNumber = size(app.Data.fibFeat,1);
            for iF = 1:featureNumber
                xc = app.UITable.Data.endpointcol(iF);
                yc = app.UITable.Data.endpointrow(iF);
                if (xc > I || xc < 1 || yc > J || yc < 1)
                    continue;
                end
                rawmap(yc,xc) = app.UITable.Data.(app.FeatureListListBox.Value)(iF);
                
            end
            %find the positions of all non-nan values
            map2 = rawmap;
            ind = find(~isnan(rawmap));
            [y,x] = ind2sub([J I],ind);
%             bndryMeas = 0;
%             if ~bndryMeas
%                 %standard deviation filter
%                 %         fSize = round(I/16);
%                 %         fSize2 = ceil(fSize/2);
%                 fSize2 = 18; %mapCP.STDfilter_size;
%                 map2 = nan(J,I);
%                 for i = 1:length(ind)
%                     %find any positions that are in a square region around the
%                     %current curvelet
%                     ind2 = find(x > x(i)-fSize2 & x < x(i)+fSize2 & y > y(i)-fSize2 & y < y(i)+fSize2);
%                     %convert these positions to linear indices
%                     ind3 = sub2ind([J I],y(ind2),x(ind2));
%                     %get all the grey level values
%                     vals = rawmap(ind3);
%                     if length(vals) > 2
% %                         %Perform the circular angle uniformity test, first scale values from 0-255 to 0-2*pi
% %                         %Then scale from 0-1 to 0-255
% %                         map2(y(i),x(i)) = (circ_r(vals*pi/127.5))*255;
%                           map2(y(i),x(i))= mean(vals(:));
%                     end
% 
%                 end
%                 %figure(600); imagesc(map2); colorbar;
%             end
            %max filter
            fSize = app.SQUAREmaxfilter_size;% YL: pass this from the interface %12;% round(J/64);  %YL: fix the fsize to 12 to make the ratio of fsize/sig =3 which is the one used in the version 2.3
            fSize2 = ceil(fSize/2);
            map4 = nan(J,I); %yl nan(size(img));
            tic
            for i = 1:length(ind)
                val = map2(y(i),x(i));
                rows = y(i)-fSize2:y(i)+fSize2;
                cols = x(i)-fSize2:x(i)+fSize2;
                %get rid of out of bounds coordinates
                ind4 = find(rows > 0 & rows < J & cols > 0 & cols < I);
                rows = rows(ind4);
                cols = cols(ind4);
                %now make a square collection of indices
                lenInd = length(ind4);
                lenInd2 = lenInd*lenInd;
                rows = repmat(rows,1,lenInd);
                cols = reshape(repmat(cols,lenInd,1),1,lenInd2);
                %get the linear indices in the original map
                ind5 = sub2ind([J I],rows,cols);
                
                
                %find the number of fibers within the filter region for normalization
                % creates a metric of alignment per fiber, normalizes away density
                % we don't really care about density, we care much more about alignment
                ind6 = find(~isnan(rawmap(ind5)));
                numFibs = length(ind6);
                
                %set the value to the max of the current or what was there
                map4(ind5) = max(map4(ind5),val/numFibs);
            end
            %figure(675); imagesc(map4); colorbar;
            %gaussian filter
            sig =  app.GAUSSIANdiscfilter_sigma; % YL: pass this from the interface % round(J/96); %in pixels; YL: fix the filter size
            h = fspecial('gaussian', [10*sig 10*sig], sig);
            %uint 8 converts nans to zeros
            procmap = imfilter(uint8(map4),h,'replicate');
            %figure(700); imagesc(procmap); colorbar;
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Don't show heatmap control panel
            app.HeatmapcontrolPanel.Visible = 'off';  
            app.BinNumberSliderLabel.Visible = 'off';
            app.BinNumberSlider.Visible = 'off';
            app.FileListListBox.Enable = 'off';
            app.FeatureListListBox.Enable = 'off';
            app.LocationButton.Enable = 'off';
            app.BoxplotButton.Enable = 'off';
            app.HistogramButton.Enable = 'off';
            app.HeatmapButton.Enable = 'off';
        end

        % Button pushed function: OpenfeatureFileButton
        function OpenfeatureFileButtonPushed(app, event)
            [fileName,app.filePath] = uigetfile({...
                '*.mat','MAT-files (*.mat)'; '*.*',  'All Files (*.*)'},'Select CurveAlign Output .mat feature file',app.filePath,'MultiSelect','off');
            if(~iscell(fileName))
                app.fileList{1} = fileName;
                app.fileSelected = fileName;
            end
            app.Data = load(fullfile(app.filePath, app.fileSelected));  % all the features from the .mat file
            %file list box
            app.FileListListBox.Items = app.fileList;
            app.FileListListBox.Value = app.FileListListBox.Items{1};
            %feature list box
            featNames = app.Data.featNames;
            featNames = cellfun(@(x) x(~isspace(x)),featNames,'UniformOutput', false);
            featNames{1} = 'FbierIndex';
            app.Data.featNames = featNames;
            app.FeatureListListBox.Items = app.Data.featNames';
            app.FeatureListListBox.Value = app.FeatureListListBox.Items{4};
            
             ImageName = strrep(app.fileSelected,'_fibFeatures.mat','');
             %show original image in a tab 
            originalImage = [ImageName '.tif'];
            imshow(fullfile(strrep(app.filePath,'CA_Out',''),originalImage),'Parent',app.UIAxes2);
            app.UIAxes2.Title.String = {strrep(originalImage,'_','-')};
            colormap(app.UIAxes2,'bone'); 
            colorbar(app.UIAxes2)
            axis(app.UIAxes2,'image','equal')
            % show overlay image in a tab
            overlayImage = [ImageName '_overlay.tiff'];
            imshow(fullfile(app.filePath,overlayImage),'Parent',app.UIAxes3)
            app.UIAxes3.Title.String = {strrep(overlayImage,'_', '-')};
            axis(app.UIAxes2,'image','equal')
            
            % Store the data in a table tab
            tData = array2table(app.Data.fibFeat, 'VariableNames',app.FeatureListListBox.Items');
            app.UITable.Data = tData;
            app.UITable.ColumnName = tData.Properties.VariableNames;
            %Store image size into the app.Data.imageWidth/imageHeight
            imageInformation = imfinfo(fullfile(strrep(app.filePath,'CA_Out',''),originalImage));
            if ~isempty(imageInformation)
                app.Data.imageWidth = imageInformation.Width;
                app.Data.imageHeight = imageInformation.Height;
            else
                app.Data.imageWidth = nan;
                app.Data.imageHeight = nan;
                error('original image %s is not located', fullfile(strrep(app.filePath,'CA_Out',''),originalImage))
            end
            app.BinNumberSlider.Visible = 'on';
            app.FileListListBox.Enable = 'on';
            app.FeatureListListBox.Enable = 'on';
            app.FeatureListListBox.Enable = 'on';
            app.LocationButton.Enable = 'on';
            app.BoxplotButton.Enable = 'on';
            app.HistogramButton.Enable = 'on';
            app.HeatmapButton.Enable = 'on';
            app.PlottingOptionsButtonGroupSelectionChanged(app)
        end

        % Callback function
        function FileListListBoxValueChanged(app, event)
           value = app.FileListListBox.Value; 
        end

        % Callback function: BinNumberSlider, FeatureListListBox, 
        % HeatmapcontrolPanel, PlottingOptionsButtonGroup
        function PlottingOptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlottingOptionsButtonGroup.SelectedObject;
            app.plotOption = selectedButton.Text;
            if ~strcmp(app.plotOption, 'Histogram')
                 % Don't show histogram slider
                app.BinNumberSliderLabel.Visible = 'off';
                app.BinNumberSlider.Visible = 'off';
            end
            if ~strcmp(app.plotOption, 'Heatmap')
                 % Don't show heatmap control panel
                app.HeatmapcontrolPanel.Visible = 'off';  
            end
            switch app.plotOption
                case 'Location'
                    cla(app.UIAxes,'reset')
%                     hold(app.UIAxes,'on')
                    backgroundIntensity = zeros(app.Data.imageHeight,app.Data.imageWidth);
                    imshow(backgroundIntensity,'Parent',app.UIAxes); 
                    hold(app.UIAxes,'on')
                    %                 featureNumber = size(app.Data.fibFeat,1);
                    Xdata = app.UITable.Data.endpointcol;
                    Ydata = app.UITable.Data.endpointrow;
                    scatter(app.UIAxes, Xdata, Ydata,'red','.')
                    title(app.UIAxes,app.fileSelected)
%                     xlabel(app.UIAxes,'');
%                     ylabel(app.UIAxes,'');
%                     xlim(app.UIAxes,[1 app.Data.imageWidth]);
%                     ylim(app.UIAxes,[1 app.Data.imageHeight]);
                    axis(app.UIAxes,'image','equal')
       
                case 'Box plot'
                    cla(app.UIAxes,'reset')
                    value = app.UITable.Data.(app.FeatureListListBox.Value);
                    boxplot(app.UIAxes,value);
                    app.UIAxes.XTickLabel = app.FeatureListListBox.Value;
                    
                    
                case 'Histogram'
                    % Show histogram slider
                    app.BinNumberSliderLabel.Visible = 'on';
                    app.BinNumberSlider.Visible = 'on';
                    cla(app.UIAxes,'reset')
%                     hold(app.UIAxes,'on')
                    value = app.UITable.Data.(app.FeatureListListBox.Value);
                    histogram(app.UIAxes,value,app.BinNumber)
                    title(app.UIAxes,app.FeatureListListBox.Value)
                    ylabel(app.UIAxes,'Frequency')
                    axis(app.UIAxes,'xy')
%                     hold(app.UIAxes,'off')
                    
                case 'Heatmap'
                    app.HeatmapcontrolPanel.Visible = 'on';
                    cla(app.UIAxes,'reset')
%                     hold(app.UIAxes,'on')                    
                    [rawmap, procmap] = CAheatmap(app);
%                      app.CAvisualization.AutoResizeChildren = 'off';
%                     ax1 = subplot(1,2,1,'Parent', app.CAvisualization);
%                     ax2 = subplot(1,2,2,'Parent', app.CAvisualization);
                    switch app.Switch2.Value
                        case 'Raw'
                            imagesc(app.UIAxes, rawmap)
                            app.Max_Filter_SizeEditField.Enable = 'off';
                            app.Gaussian_Filter_SizeEditField.Enable = 'off';
                        case 'Filtered'
                            app.Max_Filter_SizeEditField.Enable = 'on';
                            app.Gaussian_Filter_SizeEditField.Enable = 'on';                            
                            imagesc(app.UIAxes, procmap)
                    end
%                     heatmap(app.CAvisualization,rawmap);
                    axis(app.UIAxes,'image', 'equal')
                    colorbar(app.UIAxes)
                    title(app.UIAxes,app.FeatureListListBox.Value)
             end
            
        end

        % Value changing function: BinNumberSlider
        function BinNumberSliderValueChanging(app, event)
            changingValue = event.Value;
            app.BinNumber = floor(changingValue);
        end

        % Value changed function: Max_Filter_SizeEditField
        function Max_Filter_SizeEditFieldValueChanged(app, event)
            value = app.Max_Filter_SizeEditField.Value;
            app.SQUAREmaxfilter_size = value;
            app.PlottingOptionsButtonGroupSelectionChanged(app);
        end

        % Value changed function: Gaussian_Filter_SizeEditField
        function Gaussian_Filter_SizeEditFieldValueChanged(app, event)
            value = app.Gaussian_Filter_SizeEditField.Value;
            app.GAUSSIANdiscfilter_sigma = value;
            app.PlottingOptionsButtonGroupSelectionChanged(app);
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            value = app.Switch2.Value;
            switch value
                case 'Raw'
                    app.Max_Filter_SizeEditField.Enable = 'off';
                    app.Gaussian_Filter_SizeEditField.Enable = 'off';
                case 'Filtered'
                    app.Max_Filter_SizeEditField.Enable = 'on';
                    app.Gaussian_Filter_SizeEditField.Enable = 'on';
            end
            app.PlottingOptionsButtonGroupSelectionChanged(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CAvisualization = uifigure;
            app.CAvisualization.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.CAvisualization);
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.Panel = uipanel(app.VisualizationTab);
            app.OpenfeatureFileButton = uibutton(app.Panel, 'push');
            app.OpenfeatureFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfeatureFileButtonPushed, true);
            app.OpenfeatureFileButton.Text = 'Open feature File';
            app.FileListListBoxLabel = uilabel(app.Panel);
            app.FileListListBoxLabel.Text = 'FileList';
            app.FileListListBox = uilistbox(app.Panel);
            app.FeatureListListBoxLabel = uilabel(app.VisualizationTab);
            app.FeatureListListBoxLabel.Text = 'FeatureList';
            app.FeatureListListBox = uilistbox(app.VisualizationTab);
            app.FeatureListListBox.ValueChangedFcn = createCallbackFcn(app, @PlottingOptionsButtonGroupSelectionChanged, true);
            app.PlottingOptionsButtonGroup = uibuttongroup(app.VisualizationTab);
            app.PlottingOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlottingOptionsButtonGroupSelectionChanged, true);
            app.PlottingOptionsButtonGroup.Title = 'Plotting Options';
            app.LocationButton = uiradiobutton(app.PlottingOptionsButtonGroup);
            app.LocationButton.Text = 'Location';
            app.LocationButton.Value = true;
            app.BoxplotButton = uiradiobutton(app.PlottingOptionsButtonGroup);
            app.BoxplotButton.Text = 'Box plot';
            app.HistogramButton = uiradiobutton(app.PlottingOptionsButtonGroup);
            app.HistogramButton.Text = 'Histogram';
            app.HeatmapButton = uiradiobutton(app.PlottingOptionsButtonGroup);
            app.HeatmapButton.Text = 'Heatmap';
            app.UIAxes = uiaxes(app.VisualizationTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.BinNumberSliderLabel = uilabel(app.VisualizationTab);
            app.BinNumberSliderLabel.Text = 'BinNumber';
            app.BinNumberSlider = uislider(app.VisualizationTab);
            app.BinNumberSlider.Limits = [0 50];
            app.BinNumberSlider.ValueChangedFcn = createCallbackFcn(app, @PlottingOptionsButtonGroupSelectionChanged, true);
            app.BinNumberSlider.ValueChangingFcn = createCallbackFcn(app, @BinNumberSliderValueChanging, true);
            app.BinNumberSlider.Value = 10;
            app.HeatmapcontrolPanel = uipanel(app.VisualizationTab);
            app.HeatmapcontrolPanel.Title = 'Heatmap control';
            app.HeatmapcontrolPanel.SizeChangedFcn = createCallbackFcn(app, @PlottingOptionsButtonGroupSelectionChanged, true);
            app.Switch2 = uiswitch(app.HeatmapcontrolPanel, 'slider');
            app.Switch2.Items = {'Raw', 'Filtered'};
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch2.Value = 'Raw';
            app.Max_Filter_SizeEditFieldLabel = uilabel(app.HeatmapcontrolPanel);
            app.Max_Filter_SizeEditFieldLabel.Text = 'Max_Filter_Size';
            app.Max_Filter_SizeEditField = uieditfield(app.HeatmapcontrolPanel, 'numeric');
            app.Max_Filter_SizeEditField.ValueChangedFcn = createCallbackFcn(app, @Max_Filter_SizeEditFieldValueChanged, true);
            app.Max_Filter_SizeEditField.Value = 12;
            app.Gaussian_Filter_SizeEditFieldLabel = uilabel(app.HeatmapcontrolPanel);
            app.Gaussian_Filter_SizeEditFieldLabel.Text = 'Gaussian_Filter_Size';
            app.Gaussian_Filter_SizeEditField = uieditfield(app.HeatmapcontrolPanel, 'numeric');
            app.Gaussian_Filter_SizeEditField.ValueChangedFcn = createCallbackFcn(app, @Gaussian_Filter_SizeEditFieldValueChanged, true);
            app.Gaussian_Filter_SizeEditField.Value = 4;
            app.FeatureValuesTab = uitab(app.TabGroup);
            app.FeatureValuesTab.Title = 'FeatureValues';
            app.UITable = uitable(app.FeatureValuesTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.OriginalimageTab = uitab(app.TabGroup);
            app.OriginalimageTab.Title = 'Originalimage';
            app.UIAxes2 = uiaxes(app.OriginalimageTab);
            title(app.UIAxes2, 'Original Image Name')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.OverlayImageTab = uitab(app.TabGroup);
            app.OverlayImageTab.Title = 'OverlayImage';
            app.UIAxes3 = uiaxes(app.OverlayImageTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CAvisualization)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CAvisualization)
        end
    end
end