classdef intersectionProperties < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ColorDropDownLabel    matlab.ui.control.Label
        ColorDropDown         matlab.ui.control.DropDown
        SizeEditFieldLabel    matlab.ui.control.Label
        SizeEditField         matlab.ui.control.NumericEditField
        ColorDropDown_2Label  matlab.ui.control.Label
        ColorDropDown_2       matlab.ui.control.DropDown
        SizeEditField_2Label  matlab.ui.control.Label
        SizeEditField_2       matlab.ui.control.NumericEditField
        OkButton              matlab.ui.control.Button
        PointsLabel           matlab.ui.control.Label
        PointsselectedLabel   matlab.ui.control.Label
        PropertiesLabel       matlab.ui.control.Label
        ShapeDropDownLabel    matlab.ui.control.Label
        ShapeDropDown         matlab.ui.control.DropDown
        ShapeDropDown_2Label  matlab.ui.control.Label
        ShapeDropDown_2       matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        callingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, pointColor, pointSelectedColor, pointSize, pointSelectedSize, pointShape, pointSelectedShape)
            app.callingApp = mainApp;
            app.ColorDropDown.Value = pointColor;
            app.SizeEditField.Value = pointSize;
            app.ColorDropDown_2.Value = pointSelectedColor;
            app.SizeEditField_2.Value = pointSelectedSize;
            switch pointShape
                case '.'
                    app.ShapeDropDown.Value = "Point";
                case '*'
                    app.ShapeDropDown.Value = "Star";
                case 'x'
                    app.ShapeDropDown.Value = "Cross";
            end
            switch pointSelectedShape
                case '.'
                    app.ShapeDropDown_2.Value = "Point";
                case '*'
                    app.ShapeDropDown_2.Value = "Star";
                case 'x'
                    app.ShapeDropDown_2.Value = "Cross";
            end
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            app.callingApp.pointColor = value;
        end

        % Value changed function: SizeEditField
        function SizeEditFieldValueChanged(app, event)
            value = app.SizeEditField.Value;
            app.callingApp.pointSize = value;
        end

        % Value changed function: ColorDropDown_2
        function ColorDropDown_2ValueChanged(app, event)
            value = app.ColorDropDown_2.Value;
            app.callingApp.pointSelectedColor = value;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            delete(app) 
        end

        % Value changed function: SizeEditField_2
        function SizeEditField_2ValueChanged(app, event)
            value = app.SizeEditField_2.Value;
            app.callingApp.pointSelectedSize = value;
        end

        % Value changed function: ShapeDropDown
        function ShapeDropDownValueChanged(app, event)
            value = app.ShapeDropDown.Value;
            if strcmp(value, 'Point')
                app.callingApp.pointShape = '.';
            elseif strcmp(value, 'Cross')
                app.callingApp.pointShape = 'x';
            elseif strcmp(value, 'Star')
                app.callingApp.pointShape = '*';
            end
        end

        % Value changed function: ShapeDropDown_2
        function ShapeDropDown_2ValueChanged(app, event)
            value = app.ShapeDropDown_2.Value;
            if strcmp(value, 'Point')
                app.callingApp.pointSelectedShape = '.';
            elseif strcmp(value, 'Cross')
                app.callingApp.pointSelectedShape = 'x';
            elseif strcmp(value, 'Star')
                app.callingApp.pointSelectedShape = '*';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'Red', 'Yellow', 'Blue'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'Red';
            app.SizeEditFieldLabel = uilabel(app.UIFigure);
            app.SizeEditFieldLabel.Text = {'Size'; ''};
            app.SizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @SizeEditFieldValueChanged, true);
            app.SizeEditField.Value = 15;
            app.ColorDropDown_2Label = uilabel(app.UIFigure);
            app.ColorDropDown_2Label.Text = 'Color';
            app.ColorDropDown_2 = uidropdown(app.UIFigure);
            app.ColorDropDown_2.Items = {'Yellow', 'Red', 'Blue', 'Index'};
            app.ColorDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColorDropDown_2ValueChanged, true);
            app.ColorDropDown_2.Value = 'Yellow';
            app.SizeEditField_2Label = uilabel(app.UIFigure);
            app.SizeEditField_2Label.Text = {'Size'; ''};
            app.SizeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @SizeEditField_2ValueChanged, true);
            app.SizeEditField_2.Value = 15;
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = {'Ok'; ''};
            app.PointsLabel = uilabel(app.UIFigure);
            app.PointsLabel.Text = {'Points:'; ''};
            app.PointsselectedLabel = uilabel(app.UIFigure);
            app.PointsselectedLabel.Text = 'Points selected:';
            app.PropertiesLabel = uilabel(app.UIFigure);
            app.PropertiesLabel.Text = {'Properties'; ''};
            app.ShapeDropDownLabel = uilabel(app.UIFigure);
            app.ShapeDropDownLabel.Text = {'Shape'; ''};
            app.ShapeDropDown = uidropdown(app.UIFigure);
            app.ShapeDropDown.Items = {'Point', 'Cross', 'Star'};
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            app.ShapeDropDown.Value = 'Point';
            app.ShapeDropDown_2Label = uilabel(app.UIFigure);
            app.ShapeDropDown_2Label.Text = {'Shape'; ''};
            app.ShapeDropDown_2 = uidropdown(app.UIFigure);
            app.ShapeDropDown_2.Items = {'Point', 'Cross', 'Star'};
            app.ShapeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDown_2ValueChanged, true);
            app.ShapeDropDown_2.Value = 'Point';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = intersectionProperties(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end